classdef rcsPlotGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        LoadDataFoldersTab     matlab.ui.container.Tab
        DatafoldersLabel       matlab.ui.control.Label
        sessionTable           matlab.ui.control.Table
        loadfolderButton       matlab.ui.control.Button
        PlotTab                matlab.ui.container.Tab
        PlotControlsPanel      matlab.ui.container.Panel
        PlotButton             matlab.ui.control.Button
        popoutCheckBox         matlab.ui.control.CheckBox
        ChoosewhattoplotLabel  matlab.ui.control.Label
        TimeDomainPanel        matlab.ui.container.Panel
        TimeDomainListBox      matlab.ui.control.ListBox
        PowerDomainPanel       matlab.ui.container.Panel
        PowerDomainListBox     matlab.ui.control.ListBox
        AdaptivePanel          matlab.ui.container.Panel
        AdaptiveDomainListBox  matlab.ui.control.ListBox
        ActigraphyPanel        matlab.ui.container.Panel
        ActigraphyListBox      matlab.ui.control.ListBox
        MetaDataTab            matlab.ui.container.Tab
        MetaDataListBoxLabel   matlab.ui.control.Label
        MetaDataListBox        matlab.ui.control.ListBox
        UITable                matlab.ui.control.Table
    end

    
    properties (Access = public)
        dataTable % combined daata table
        dirName  % directory with data
        rc % rcsPlotter object
        fieldnames
        settingsStruc
    end
    
    methods (Access = public)
        
        function loadData(app,dirname)
            app.rc.addFolder(dirname);
            app.rc.loadData();
            if ~isempty( app.rc.Data(end))
                app.settingsStruc.fftSettings = app.rc.Data(end).fftSettings;
                app.settingsStruc.timeDomainSettings = app.rc.Data(end).timeDomainSettings;
                app.settingsStruc.stimSettingsOut = app.rc.Data(end).stimSettingsOut;
                app.settingsStruc.DetectorSettings = app.rc.Data(end).DetectorSettings;
                app.settingsStruc.AdaptiveStimSettings = app.rc.Data(end).AdaptiveStimSettings;
                app.settingsStruc.AdaptiveRuns_StimSettings = app.rc.Data(end).AdaptiveEmbeddedRuns_StimSettings;
                app.settingsStruc.powerSettings = app.rc.Data(end).powerSettings;
                app.settingsStruc.metaData = app.rc.Data(end).metaData;
                
                
                app.dataTable = app.rc.Data(1).combinedDataTable;
                % populate the headers
                % time domain
                app.fieldnames = app.dataTable.Properties.VariableNames';
                
                % time domain:
                idxtd = cellfun(@(x) any(strfind(x,'TD_k')) ,app.fieldnames);
                app.TimeDomainListBox.Items = app.fieldnames(idxtd);
                
                % power domain
                idxtd = cellfun(@(x) any(strfind(x,'Power_Band')) ,app.fieldnames);
                app.PowerDomainListBox.Items = app.fieldnames(idxtd);
                
                % Adaptive
                adaptiveStatesUse = {'Adaptive_CurrentAdaptiveState',...
                    'Adaptive_CurrentProgramAmplitudesInMilliamps',...
                    'Adaptive_Ld0DetectionStatus',...
                    'Adaptive_Ld1DetectionStatus'};
                app.AdaptiveDomainListBox.Items = adaptiveStatesUse;
                
                % Actigraphy
                idxtd = cellfun(@(x) any(strfind(x,'Accel_')) ,app.fieldnames);
                app.ActigraphyListBox.Items = app.fieldnames(idxtd);
                app.ActigraphyListBox.Items = app.ActigraphyListBox.Items(1:3); % ugly hack
                
                % set up meta data table
                app.MetaDataListBox.Items = fieldnames(app.settingsStruc);
                app.MetaDataListBox.Value = 'timeDomainSettings';
                app.UITable.Data = app.settingsStruc.timeDomainSettings;
                app.UITable.ColumnName = app.settingsStruc.timeDomainSettings.Properties.VariableNames;
                
                % set up session viewer
                app.sessionTable.Data = app.rc.SessionTable;
                app.sessionTable.ColumnName = app.rc.SessionTable.Properties.VariableNames;
                
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            app.rc = rcsPlotter();
            if nargin == 1
                % no data was passed to function
            end
            if nargin == 2
                dirnames = varargin{1};
                for d = 1:length(dirnames)
                    loadData(app,dirnames{d});
                end
            end
            
            
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            % plot some data:
            fieldnamesplot = [app.TimeDomainListBox.Value, ...
                app.PowerDomainListBox.Value,...
                app.AdaptiveDomainListBox.Value,...
                app.ActigraphyListBox.Value];
            nplots = length(fieldnamesplot);
            if app.popoutCheckBox.Value
                hfig = figure;
                hfig.Color = 'w';
                parentUse = hfig;
            else % in future version consider plotting in another tab in gui
                hfig = figure;
                hfig.Color = 'w';
                parentUse = hfig;
            end
            hsb = gobjects();
            nrows = nplots;
            for n = 1:nrows
                hsb(n,1) = subplot(nrows,1,n);
            end
            cntplt = 1;
            
            
            for p = 1:nplots
                % for time domain data, allow line breaks, all other types
                % of data, just "join lines"
                if any(strfind(fieldnamesplot{p},'TD'))
                    chan = str2num(fieldnamesplot{p}(end))+1;
                    app.rc.plotTdChannel(chan,hsb(cntplt,1));
                    cntplt = cntplt + 1;
                elseif any(strfind(fieldnamesplot{p},'Power'))
                    chan = str2num(fieldnamesplot{p}(end));
                    app.rc.plotPowerRaw(chan,hsb(cntplt,1));
                    cntplt = cntplt + 1;
                elseif any(strfind(fieldnamesplot{p},'Adaptive'))
                    switch fieldnamesplot{p}
                        case 'Adaptive_CurrentAdaptiveState'
                            app.rc.plotAdaptiveState(hsb(cntplt,1));
                            cntplt = cntplt + 1;
                        case 'Adaptive_CurrentProgramAmplitudesInMilliamps'
                            app.rc.plotAdaptiveCurrent(0,hsb(cntplt,1)); % assumed only program 0 for now
                            cntplt = cntplt + 1;
                        case 'Adaptive_Ld0DetectionStatus'
                            app.rc.plotAdaptiveLd(0,hsb(cntplt,1)); 
                            cntplt = cntplt + 1;
                        case 'Adaptive_Ld1DetectionStatus'
                            app.rc.plotAdaptiveLd(1,hsb(cntplt,1)); 
                            cntplt = cntplt + 1;
                    end
                elseif any(strfind(fieldnamesplot{p},'Accel'))
                    switch fieldnamesplot{p}
                        case 'Accel_XSamples'
                            app.rc.plotActigraphyChannel('X',hsb(cntplt,1)); 
                            cntplt = cntplt + 1;
                        case 'Accel_YSamples'
                            app.rc.plotActigraphyChannel('Y',hsb(cntplt,1)); 
                            cntplt = cntplt + 1;
                        case 'Accel_ZSamples'
                            app.rc.plotActigraphyChannel('Z',hsb(cntplt,1)); 
                            cntplt = cntplt + 1;
                    end
                end
            end
            linkaxes(hsb,'x')
        end

        % Value changed function: MetaDataListBox
        function MetaDataListBoxValueChanged(app, event)
            value = app.MetaDataListBox.Value;
            app.UITable.Data = app.settingsStruc.(value);
            app.UITable.ColumnName = app.settingsStruc.(value).Properties.VariableNames;
        end

        % Button pushed function: loadfolderButton
        function loadfolderButtonPushed(app, event)
            dirname = uigetdir(pwd,'choose dir with rc+s json files');
            loadData(app,dirname)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataFoldersTab = uitab(app.TabGroup);
            app.LoadDataFoldersTab.Title = 'Load Data Folders';
            app.DatafoldersLabel = uilabel(app.LoadDataFoldersTab);
            app.DatafoldersLabel.Text = 'Data folders:';
            app.sessionTable = uitable(app.LoadDataFoldersTab);
            app.sessionTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.sessionTable.RowName = {};
            app.loadfolderButton = uibutton(app.LoadDataFoldersTab, 'push');
            app.loadfolderButton.ButtonPushedFcn = createCallbackFcn(app, @loadfolderButtonPushed, true);
            app.loadfolderButton.Text = 'load folder';
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.PlotControlsPanel = uipanel(app.PlotTab);
            app.PlotControlsPanel.Title = 'Plot Controls';
            app.PlotButton = uibutton(app.PlotControlsPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.popoutCheckBox = uicheckbox(app.PlotControlsPanel);
            app.popoutCheckBox.Tooltip = {'If this is selected it will create a new figure with the data'; ' else'; ' it will plot the data in the "plot" tab'};
            app.popoutCheckBox.Text = 'pop out';
            app.popoutCheckBox.Value = true;
            app.ChoosewhattoplotLabel = uilabel(app.PlotTab);
            app.ChoosewhattoplotLabel.Text = 'Choose what to plot: ';
            app.TimeDomainPanel = uipanel(app.PlotTab);
            app.TimeDomainPanel.Title = 'Time Domain';
            app.TimeDomainListBox = uilistbox(app.TimeDomainPanel);
            app.TimeDomainListBox.Multiselect = 'on';
            app.TimeDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.TimeDomainListBox.Value = {};
            app.PowerDomainPanel = uipanel(app.PlotTab);
            app.PowerDomainPanel.Title = 'Power Domain';
            app.PowerDomainListBox = uilistbox(app.PowerDomainPanel);
            app.PowerDomainListBox.Multiselect = 'on';
            app.PowerDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.PowerDomainListBox.Value = {};
            app.AdaptivePanel = uipanel(app.PlotTab);
            app.AdaptivePanel.Title = 'Adaptive';
            app.AdaptiveDomainListBox = uilistbox(app.AdaptivePanel);
            app.AdaptiveDomainListBox.Multiselect = 'on';
            app.AdaptiveDomainListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.AdaptiveDomainListBox.Value = {};
            app.ActigraphyPanel = uipanel(app.PlotTab);
            app.ActigraphyPanel.Title = 'Actigraphy';
            app.ActigraphyListBox = uilistbox(app.ActigraphyPanel);
            app.ActigraphyListBox.Multiselect = 'on';
            app.ActigraphyListBox.Tooltip = {'Select one or more channels you wish to plot then hit "plot" '};
            app.ActigraphyListBox.Value = {};
            app.MetaDataTab = uitab(app.TabGroup);
            app.MetaDataTab.Title = 'Meta Data';
            app.MetaDataListBoxLabel = uilabel(app.MetaDataTab);
            app.MetaDataListBoxLabel.Text = 'Meta Data';
            app.MetaDataListBox = uilistbox(app.MetaDataTab);
            app.MetaDataListBox.ValueChangedFcn = createCallbackFcn(app, @MetaDataListBoxValueChanged, true);
            app.UITable = uitable(app.MetaDataTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rcsPlotGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end